classdef AppPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Volume0Label                 matlab.ui.control.Label
        NoteID0showsall0blocksLabel  matlab.ui.control.Label
        IDSpinner                    matlab.ui.control.Spinner
        IDLabel                      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = public)
        bData
        bID
        bVol
        hPatch
        defLim
    end
    
    methods (Access = public)
        function Set_blockData(app, tbData)
            tbID   = tbData.get_blockID();
            conVol = tbData.get_volume();
            
            nb = size(tbID,1);
            tbVol = zeros(tbData.count,1);
            for ii = 1:nb
                for jj = 1:tbID(ii,2)
                    tbVol(ii) = tbVol(ii) + conVol(tbID(ii,1) + jj);
                end
            end
            
            % assgin           
            app.bData= tbData;
            app.bID  = tbID;
            app.bVol = tbVol;
            app.plot_block();
%             defLim = [app.UIAxes.XLim, app.UIAxes.YLim, app.UIAxes.ZLim];
            
            % UI
            app.IDSpinner.Limits = [0, size(app.bID,1)];
            app.IDSpinner.Value = 0;            
            
            app.NoteID0showsall0blocksLabel.Text = ...
                {'Note: '
                ['ID = 0 shows all [', num2str(nb),'] blocks']};
            
        end
    end
    
    methods (Access = private)
        function plot_block(app, b)    
            if nargin == 1
                icons = 1:app.bData.count;
            else
                icons = app.bID(b,1)+1:app.bID(b,1)+app.bID(b,2);
            end
            [cors,fs] = app.bData.get_plotPatch(icons);


%             
%             
%             % cal fnpMax and 
%             
%             fnpMax = 0;     nf =0;    
%             for ii = icons
%                 tF = obj.F{ii};  tnf = size(tF,1);
%                 for jj =1:tnf
%                     cc = size(tF{jj},2);
%                     if cc > fnpMax
%                         fnpMax = cc ;
%                     end
%                 end
%                 nf = nf + tnf;
%             end
%             
%             % set fs
%             fs = nan(nf, fnpMax);   iface = 0;   p0 = 0;
%             for ii = icons
%                 tF = obj.F{ii};   
%                 for jj =1:size(tF,1)
%                     fs(iface+1,1: size(tF{jj},2)) = tF{jj} + p0;
%                     
%                     % next
%                     iface = iface + 1;
%                 end
%                 p0 = p0 + size(obj.Cor{ii},1);
%             end
            
            if nargin == 1
                app.Volume0Label.Text = ["Volume: ", num2str(sum(app.bVol))];
            else
                app.Volume0Label.Text = ["Volume: ", num2str(app.bVol(b))];
            end
            set(app.hPatch, 'Faces',fs,'Vertices',cors);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hPatch = patch(app.UIAxes,'Faces',[],'Vertices',[],...
                'FaceColor','#4DBEEE');
        end

        % Value changed function: IDSpinner
        function IDSpinnerValueChanged(app, event)
            value = app.IDSpinner.Value;
            if isempty(app.bData)
                return
            elseif value == 0
                app.plot_block()    
            else
                app.plot_block(value)    
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID ';
            app.IDSpinner = uispinner(app.UIFigure);
            app.IDSpinner.ValueChangedFcn = createCallbackFcn(app, @IDSpinnerValueChanged, true);
            app.NoteID0showsall0blocksLabel = uilabel(app.UIFigure);
            app.NoteID0showsall0blocksLabel.Text = {'Note: '; 'ID = 0 shows all [0] blocks '};
            app.Volume0Label = uilabel(app.UIFigure);
            app.Volume0Label.Text = 'Volume: 0                 ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppPlot

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end